classdef trasfer_planner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure         % UI Figure
        axes         matlab.ui.control.UIAxes % Orbits
        LabelSlider  matlab.ui.control.Label  % Time
        Slider       matlab.ui.control.Slider % [0 300]
        LabelGauge   matlab.ui.control.Label  % Kerbin
        KerbinGauge  matlab.ui.control.Gauge  % [0 360]
        Label        matlab.ui.control.Label  % Duna
        DunaGauge    matlab.ui.control.Gauge  % [0 360]
        LabelSlider2 matlab.ui.control.Label  % ∆V
        deltaV       matlab.ui.control.Slider % [-1000 1000]
        LabelKnob    matlab.ui.control.Label  % Kerbin
        KerbinKnob   matlab.ui.control.Knob   % [0 360]
        LabelKnob2   matlab.ui.control.Label  % Duna
        DunaKnob     matlab.ui.control.Knob   % [0 360]
    end

    
    properties (Access = private)
        Kerbin    = struct('A', 13599840256, 'mu',3.5316000E12, 'R',600000,'phi0',0,'color','blue','phi',0, 'h', []); % Planet Kerbin
        Duna      = struct('A', 20726155264, 'mu',3.0136321E11, 'R',320000,'phi0',0,'color','red','phi',0, 'h', []); % Planet Duna
        Satellite = struct('A',0,'e',0, 'x0', 0, 'y0', 0, 'x', 0, 'y', 0, 'phi', 0, 'phi0', 0, 'h', [], 'hOrb', []);
        mu = 1.1723328E18;% Mu of Kerbol

    end
    
    methods (Access = private)
        
        function draw_planet_orbit(app, planet)
            % draw planet orbit
            phi = [0:1:360]/180*pi;
            R   = repmat(app.(planet).A,1,numel(phi));
            [x,y] = pol2cart(phi,R);
            plot(app.axes,x,y,'k-');
        end
        
        function draw_planet_position(app,planet)    
            [x,y] = pol2cart(app.(planet).phi,app.(planet).A);
            if(ishandle(app.(planet).h))
                app.(planet).h.XData = x;
                app.(planet).h.YData = y;
            else
                app.(planet).h = plot(app.axes,x,y,'.','color',app.(planet).color);
            end
        end
        
        function Phi = get_planet_phi(app, planet, varargin)
            if(nargin == 3)
                T = varargin{1}*86400; % Convert to seconds
            else                
                T = app.Slider.Value*86400;
            end
            Phi = TimeToMeanAnomaly(app.mu, app.(planet).A, app.(planet).phi0, T);
            Phi = rem(Phi,2*pi);
            app.(planet).phi = Phi;
        end
        
        function Phi = get_sattelite_phi(app, varargin)            
            if(nargin == 2)
                T = varargin{1}*86400; % Convert to seconds
            else                
                T = app.Slider.Value*86400;
            end
            M0 = TrueToMeanAnomaly(app.Satellite.e, app.Satellite.phi0);
            M = TimeToMeanAnomaly( app.mu, app.Satellite.a, M0, T );
            Phi = MeanToTrueAnomaly(app.Satellite.e, M); 
            app.Satellite.phi = Phi; 
        end

        function draw_orbit(app)
            
                 a=app.Satellite.a; % major semi-axes
                 b=a*sqrt(1-app.Satellite.e^2); % minor semi-axes
                 
                 [x1,y1] = pol2cart(0,app.Kerbin.A);
                 W = pi;
                 x2 = 2*a*cos(W) + x1;
                 y2 = 2*a*sin(W) + y1;
                 %[x2,y2] = pol2cart(pi,app.Duna.A);
                 
                 phi = [0:1:360]/180*pi;
                 X = a*cos(phi);
                 Y = b*sin(phi);
                 w = atan2(y2-y1,x2-x1);
                 x = (x1+x2)/2 + X*cos(w) - Y*sin(w);
                 y = (y1+y2)/2 + X*sin(w) + Y*cos(w);

                 if(ishandle(app.Satellite.hOrb) )
                     app.Satellite.hOrb.XData  = x;
                     app.Satellite.hOrb.YData  = y;
                 else
                     app.Satellite.hOrb = plot(app.axes,x,y,'b');
                 end
        end
        
        function draw_satellite_position(app)
            
                 a=app.Satellite.a; % major semi-axes
                 b=a*sqrt(1-app.Satellite.e^2); % minor semi-axes
                 
                 [x1,y1] = pol2cart(0,app.Kerbin.A);
                 W = pi;
                 x2 = 2*a*cos(W) + x1;
                 y2 = 2*a*sin(W) + y1;
                 %[x2,y2] = pol2cart(pi,app.Duna.A);
                 
                 phi = pi+app.Satellite.phi;
                 X = a*cos(phi);
                 Y = b*sin(phi);
                 w = atan2(y2-y1,x2-x1);
                 x = (x1+x2)/2 + X*cos(w) - Y*sin(w);
                 y = (y1+y2)/2 + X*sin(w) + Y*cos(w);

                 if(ishandle(app.Satellite.h) )
                     app.Satellite.h.XData  = x;
                     app.Satellite.h.YData  = y;                     
                 else                     
                     app.Satellite.h = plot(app.axes,x,y,'.','Color','k');
                 end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.axes,'on');
            app.draw_planet_orbit('Kerbin');
            app.draw_planet_orbit('Duna');
            app.draw_planet_position('Kerbin');
            app.draw_planet_position('Duna');
            
            % initial orbit calculation
           Vh   = VHomman(app.mu,app.Kerbin.A,app.Duna.A);            
           [app.Satellite.a,app.Satellite.e] = OrbitalParam(app.mu,Vh+app.deltaV.Value,app.Kerbin.A,pi/2);            
           app.draw_orbit();
           app.draw_satellite_position();
            
        end

        % Slider value changing function
        function SliderValueChanging(app, event)
            changingValue = event.Value; drawnow;
            app.get_planet_phi('Kerbin',changingValue);
            app.get_planet_phi('Duna',changingValue);
            app.get_sattelite_phi(changingValue);
            
            app.KerbinGauge.Value = rem(app.Kerbin.phi,2*pi)*180/pi;
            app.DunaGauge.Value = rem(app.Duna.phi,2*pi)*180/pi;           
            
            app.draw_planet_position('Kerbin');
            app.draw_planet_position('Duna');            
            app.draw_satellite_position();
        end

        % deltaV value changing function
        function deltaVValueChanging(app, event)
            changingValue = event.Value;
            Vh   = VHomman(app.mu,app.Kerbin.A,app.Duna.A); 
            [app.Satellite.a,app.Satellite.e] = OrbitalParam(app.mu,Vh+changingValue,app.Kerbin.A,pi/2);
            app.draw_orbit();
            
            app.Satellite.phi = app.get_sattelite_phi();
            app.draw_satellite_position();
        end

        % DunaKnob value changing function
        function DunaKnobValueChanging(app, event)
            app.Duna.phi0 = event.Value/180*pi; 
            app.get_planet_phi('Duna');
            app.draw_planet_position('Duna');            
            app.DunaGauge.Value = rem((app.Duna.phi),2*pi)*180/pi;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Orbits');
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Time';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 300];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.LabelGauge = uilabel(app.UIFigure);
            app.LabelGauge.Text = 'Kerbin';
            app.KerbinGauge = uigauge(app.UIFigure, 'circular');
            app.KerbinGauge.Limits = [0 360];
            app.KerbinGauge.ScaleDirection = 'counterclockwise';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Duna';
            app.DunaGauge = uigauge(app.UIFigure, 'circular');
            app.DunaGauge.Limits = [0 360];
            app.DunaGauge.ScaleDirection = 'counterclockwise';
            app.LabelSlider2 = uilabel(app.UIFigure);
            app.LabelSlider2.Text = '∆V';
            app.deltaV = uislider(app.UIFigure);
            app.deltaV.Limits = [-1000 1000];
            app.deltaV.ValueChangingFcn = createCallbackFcn(app, @deltaVValueChanging, true);
            app.LabelKnob = uilabel(app.UIFigure);
            app.LabelKnob.Text = 'Kerbin';
            app.KerbinKnob = uiknob(app.UIFigure, 'continuous');
            app.KerbinKnob.Limits = [0 360];
            app.KerbinKnob.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.LabelKnob2 = uilabel(app.UIFigure);
            app.LabelKnob2.Text = 'Duna';
            app.DunaKnob = uiknob(app.UIFigure, 'continuous');
            app.DunaKnob.Limits = [0 360];
            app.DunaKnob.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.DunaKnob.ValueChangingFcn = createCallbackFcn(app, @DunaKnobValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = trasfer_planner()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end